classdef SimulationRun < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SaveOutputButton              simulink.ui.control.SaveOutputButton
        LatitudeSpinner               matlab.ui.control.Spinner
        DayofMonthSpinnerLabel_2      matlab.ui.control.Label
        LongitudeSpinner              matlab.ui.control.Spinner
        TimeDifferenceSpinnerLabel_2  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DayofMonthSpinner             matlab.ui.control.Spinner
        DayofMonthSpinnerLabel        matlab.ui.control.Label
        LocationDropDown              matlab.ui.control.DropDown
        LocationDropDownLabel         matlab.ui.control.Label
        TimeDifferenceSpinner         matlab.ui.control.Spinner
        TimeDifferenceSpinnerLabel    matlab.ui.control.Label
        SimulationControls            simulink.ui.control.SimulationControls
        RightPanel                    matlab.ui.container.Panel
        TimeScope2                    matlab.ui.scope.TimeScope
        TimeScope                     matlab.ui.scope.TimeScope
        LatitudeValueLabel            matlab.ui.control.Label
        LongitudeValueLabel           matlab.ui.control.Label
        LatitudeLabel                 matlab.ui.control.Label
        LongitudeLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        GeographicAxesObject    matlab.graphics.axis.GeographicAxes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Index = readtable("Location_Data_Updated.xlsx",ReadVariableNames=true)
            app.LocationDropDown.Items = Index.Location_Name
            app.UITable.Data = readtable("Location_Data_Updated.xlsx", Sheet=int2str(Index.Location_ID(1)))
            app.LongitudeValueLabel.Text = num2str(Index.Longitude(app.LocationDropDown.ValueIndex))
            app.LatitudeValueLabel.Text = num2str(Index.Latitude(app.LocationDropDown.ValueIndex))
            app.LongitudeSpinner.Value = Index.Longitude(app.LocationDropDown.ValueIndex)
            app.LatitudeSpinner.Value = Index.Latitude(app.LocationDropDown.ValueIndex)
            

            % Create geographic axes
            app.GeographicAxesObject = geoaxes(app.RightPanel, ...
                NextPlot="add",Basemap="colorterrain", Position=[0.1,0.1,0.4,0.8],...
                MapCenter=[Index.Latitude(1), Index.Longitude(1)],ZoomLevel=5.7);
            text(app.GeographicAxesObject, Index.Latitude(1), Index.Longitude(1), "\bf*" + Index.Location_Name(1))
            % Disable panning and zooming (since R2024a)
            app.GeographicAxesObject.InteractionOptions.PanSupported = "off";
            app.GeographicAxesObject.InteractionOptions.ZoomSupported = "off";
        
            % Add title
            title(app.GeographicAxesObject,"Selected Coordinates")
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            value = app.LocationDropDown.Value;
            Index = readtable("Location_Data_Updated.xlsx")
            app.GeographicAxesObject.MapCenter = [Index.Latitude(app.LocationDropDown.ValueIndex), Index.Longitude(app.LocationDropDown.ValueIndex)];
            text(app.GeographicAxesObject, Index.Latitude(app.LocationDropDown.ValueIndex), Index.Longitude(app.LocationDropDown.ValueIndex), "\bf*" + Index.Location_Name(app.LocationDropDown.ValueIndex))
            app.UITable.Data = readtable("Location_Data_Updated.xlsx", Sheet=int2str(Index.Location_ID(app.LocationDropDown.ValueIndex)))
            app.LongitudeValueLabel.Text = num2str(Index.Longitude(app.LocationDropDown.ValueIndex))
            app.LatitudeValueLabel.Text = num2str(Index.Latitude(app.LocationDropDown.ValueIndex))
            app.LongitudeSpinner.Value = Index.Longitude(app.LocationDropDown.ValueIndex)
            app.LatitudeSpinner.Value = Index.Latitude(app.LocationDropDown.ValueIndex)
                                          
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulationControls = uisimcontrols(app.LeftPanel);
            app.SimulationControls.Simulation = app.Simulation;
            app.TimeDifferenceSpinnerLabel = uilabel(app.LeftPanel);
            app.TimeDifferenceSpinnerLabel.Text = 'TimeDiff';
            app.TimeDifferenceSpinner = uispinner(app.LeftPanel);
            app.LocationDropDownLabel = uilabel(app.LeftPanel);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.LeftPanel);
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.DayofMonthSpinnerLabel = uilabel(app.LeftPanel);
            app.DayofMonthSpinnerLabel.Text = 'DayNum';
            app.DayofMonthSpinner = uispinner(app.LeftPanel);
            app.DayofMonthSpinner.Limits = [1 28];
            app.DayofMonthSpinner.RoundFractionalValues = 'on';
            app.DayofMonthSpinner.Value = 1;
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Parameter'; 'JAN'; 'FEB'; 'MAR'; 'APR'; 'MAY'; 'JUN'; 'JUL'; 'AUG'; 'SEP'; 'OCT'; 'NOV'; 'DEC'; 'Annual'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.TimeDifferenceSpinnerLabel_2 = uilabel(app.LeftPanel);
            app.TimeDifferenceSpinnerLabel_2.Text = 'Longitude';
            app.LongitudeSpinner = uispinner(app.LeftPanel);
            app.LongitudeSpinner.Limits = [-180 180];
            app.LongitudeSpinner.Editable = 'off';
            app.LongitudeSpinner.Enable = 'off';
            app.DayofMonthSpinnerLabel_2 = uilabel(app.LeftPanel);
            app.DayofMonthSpinnerLabel_2.Text = 'Latitude';
            app.LatitudeSpinner = uispinner(app.LeftPanel);
            app.LatitudeSpinner.Limits = [-90 90];
            app.LatitudeSpinner.Editable = 'off';
            app.LatitudeSpinner.Enable = 'off';
            app.SaveOutputButton = uisimdatabutton('saveoutput', app.LeftPanel);
            app.SaveOutputButton.Simulation = app.Simulation;
            app.RightPanel = uipanel(app.GridLayout);
            app.LongitudeLabel = uilabel(app.RightPanel);
            app.LongitudeLabel.Text = 'Longitude:';
            app.LatitudeLabel = uilabel(app.RightPanel);
            app.LatitudeLabel.Text = 'Latitude:';
            app.LongitudeValueLabel = uilabel(app.RightPanel);
            app.LatitudeValueLabel = uilabel(app.RightPanel);
            app.TimeScope = uitimescope(app.RightPanel);
            app.TimeScope.LegendNames = {'From18:1'};
            app.TimeScope.Title = 'Solar Altitude (^o)';
            app.TimeScope.XLabel = 'Time (100s = 24 hours)';
            app.TimeScope.YLabel = 'Angle (Degrees)';
            app.TimeScope.XLimits = [0 1200];
            app.TimeScope.YLimits = [-100 100];
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope.XTimeSpan = 1200;
            app.TimeScope2 = uitimescope(app.RightPanel);
            app.TimeScope2.LegendNames = {'From19:1'};
            app.TimeScope2.Title = 'Solar Azimuth (^o)';
            app.TimeScope2.XLabel = 'Time (100s = 24 hours)';
            app.TimeScope2.YLabel = 'Angle (Degrees)';
            app.TimeScope2.XLimits = [0 1200];
            app.TimeScope2.YLimits = [-190 190];
            app.TimeScope2.YLimitsMethod = 'manual';
            app.TimeScope2.XTimeSpan = 1200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.DayofMonthSpinner, 'Value', app.Simulation.TunableVariables, 'DayNum');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From18:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From19:1'], app.TimeScope2);
            bind(app.LatitudeSpinner, 'Value', app.Simulation.TunableVariables, 'Latitude');
            bind(app.LongitudeSpinner, 'Value', app.Simulation.TunableVariables, 'Longitude');
            bind(app.TimeDifferenceSpinner, 'Value', app.Simulation.TunableVariables, 'TimeDiff');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulationRun

            % Associate the Simulink Model
            app.Simulation = simulation('Solar_Position');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end